classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SineWavePanel                matlab.ui.container.Panel
        SquareWavePanel              matlab.ui.container.Panel
        DutyCylceSliderLabel         matlab.ui.control.Label
        DutyCylceSlider              matlab.ui.control.Slider
        AmplitudeSlider_2Label       matlab.ui.control.Label
        AmplitudeSlider_2            matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        FrequencySliderLabel         matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        PhaseSliderLabel             matlab.ui.control.Label
        PhaseSlider                  matlab.ui.control.Slider
        TriangularWavePanel          matlab.ui.container.Panel
        AmplitudeSlider_3Label       matlab.ui.control.Label
        AmplitudeSlider_3            matlab.ui.control.Slider
        FrequencySlider_2Label       matlab.ui.control.Label
        FrequencySlider_2            matlab.ui.control.Slider
        SawtoothWavePanel            matlab.ui.container.Panel
        AmplitudeSlider_4Label       matlab.ui.control.Label
        AmplitudeSlider_4            matlab.ui.control.Slider
        DefaultSineWaveButton        matlab.ui.control.StateButton
        Panel                        matlab.ui.container.Panel
        XlimitSliderLabel            matlab.ui.control.Label
        XlimitSlider                 matlab.ui.control.Slider
        YlimitSliderLabel            matlab.ui.control.Label
        YlimitSlider                 matlab.ui.control.Slider
        SampleRateSliderLabel        matlab.ui.control.Label
        SampleRateSlider             matlab.ui.control.Slider
        DefaultSquareWaveButton      matlab.ui.control.StateButton
        DefaultTriangularWaveButton  matlab.ui.control.StateButton
        DefaultSawToothButton        matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        time
       sinewave
      sinamp
     sinfre
    sinph % Description
    sqamp
    sqfre
    sqduty
    squarewave
    triamp
    trifre
    triangularwave
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DefaultSineWaveButton
        function DefaultSineWaveButtonValueChanged(app, event)
            value = app.DefaultSineWaveButton.Value;
            if(value==1)
            app.time=linspace(0,1,1000);
            app.sinamp=1;
            app.sinfre=1;
            app.sinph=0;
            app.sinewave=app.sinamp*sin(2*pi*app.sinfre*app.time+(app.sinph));
            plot(app.UIAxes,app.time,app.sinewave);
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Analog Sine Wave');
            else            
            app.time=linspace(0,1,1000);
            plot(app.UIAxes,app.time,0);
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            end
            
        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            if(app.DefaultSineWaveButton.Value==1)
            app.sinamp = event.Value;
            app.sinewave=app.sinamp*sin(2*pi*app.sinfre*app.time+(app.sinph));
            plot(app.UIAxes,app.time,app.sinewave);
            end
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            app.sinfre = event.Value;
            app.sinewave=app.sinamp*sin(2*pi*app.sinfre*app.time+(app.sinph));
            plot(app.UIAxes,app.time,app.sinewave);
        end

        % Value changing function: PhaseSlider
        function PhaseSliderValueChanging(app, event)
            app.sinph = event.Value;
            app.sinewave=app.sinamp*sin(2*pi*app.sinfre*app.time+(app.sinph));
            plot(app.UIAxes,app.time,app.sinewave);
        end

        % Value changed function: DefaultSquareWaveButton
        function DefaultSquareWaveButtonValueChanged(app, event)
            value = app.DefaultSquareWaveButton.Value;
            if(value==1)
                app.time=linspace(0,1,1000);
                app.sqamp=1;
                app.sqfre=1;
                app.sqduty=50;
                app.squarewave=square(2*pi*app.sqfre*app.time,50);
                plot(app.UIAxes,app.time,app.squarewave);
                xlabel(app.UIAxes,'Time');
                ylabel(app.UIAxes,'Amplitude');
                title(app.UIAxes,'Analog Square Wave');
            else 
            app.time=linspace(0,1,1000);
            plot(app.UIAxes,app.time,0);
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            end
        end

        % Value changing function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanging(app, event)
            app.sqamp = event.Value;
            app.squarewave=app.sqamp*square(app.time,app.sqduty);
            plot(app.UIAxes,app.time,app.squarewave);
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Analog Square Wave');
        end

        % Value changed function: DefaultTriangularWaveButton
        function DefaultTriangularWaveButtonValueChanged(app, event)
            value = app.DefaultTriangularWaveButton.Value;
            if(value==1)
                app.triamp=1;
                app.trifre=1;
                app.time=linspace(0,1,1000);
                app.triangularwave=app.triamp*sawtooth(2*pi*app.trifre*app.time,1/2);
                plot(app.UIAxes,app.time,app.triangularwave);
                xlabel(app.UIAxes,'Time');
                ylabel(app.UIAxes,'Amplitude');
                title(app.UIAxes,'Analog Triangular Wave');
            else 
                app.time=linspace(0,1,1000);
                plot(app.UIAxes,app.time,0);
                xlabel(app.UIAxes,'Time');
                ylabel(app.UIAxes,'Amplitude');
            end
        end

        % Value changing function: AmplitudeSlider_3
        function AmplitudeSlider_3ValueChanging(app, event)
            app.triamp = event.Value;
            app.triangularwave=app.triamp*sawtooth(app.time,app.trifre);
            plot(app.UIAxes,app.time,app.triangularwave);
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Analog Triangular Wave');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SineWavePanel = uipanel(app.UIFigure);
            app.SineWavePanel.Title = 'SineWave';
            app.SquareWavePanel = uipanel(app.SineWavePanel);
            app.SquareWavePanel.Title = 'SquareWave';
            app.DutyCylceSliderLabel = uilabel(app.SquareWavePanel);
            app.DutyCylceSliderLabel.Enable = 'off';
            app.DutyCylceSliderLabel.Text = 'DutyCylce';
            app.DutyCylceSlider = uislider(app.SquareWavePanel);
            app.DutyCylceSlider.Enable = 'off';
            app.AmplitudeSlider_2Label = uilabel(app.SquareWavePanel);
            app.AmplitudeSlider_2Label.Enable = 'off';
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSlider_2 = uislider(app.SquareWavePanel);
            app.AmplitudeSlider_2.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanging, true);
            app.AmplitudeSlider_2.Enable = 'off';
            app.AmplitudeSliderLabel = uilabel(app.SineWavePanel);
            app.AmplitudeSliderLabel.Enable = 'off';
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.SineWavePanel);
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.AmplitudeSlider.Enable = 'off';
            app.FrequencySliderLabel = uilabel(app.SineWavePanel);
            app.FrequencySliderLabel.Enable = 'off';
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.SineWavePanel);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Enable = 'off';
            app.PhaseSliderLabel = uilabel(app.SineWavePanel);
            app.PhaseSliderLabel.Enable = 'off';
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.SineWavePanel);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.PhaseSlider.Enable = 'off';
            app.TriangularWavePanel = uipanel(app.UIFigure);
            app.TriangularWavePanel.Title = 'TriangularWave';
            app.AmplitudeSlider_3Label = uilabel(app.TriangularWavePanel);
            app.AmplitudeSlider_3Label.Enable = 'off';
            app.AmplitudeSlider_3Label.Text = 'Amplitude';
            app.AmplitudeSlider_3 = uislider(app.TriangularWavePanel);
            app.AmplitudeSlider_3.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSlider_3ValueChanging, true);
            app.AmplitudeSlider_3.Enable = 'off';
            app.FrequencySlider_2Label = uilabel(app.TriangularWavePanel);
            app.FrequencySlider_2Label.Enable = 'off';
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FrequencySlider_2 = uislider(app.TriangularWavePanel);
            app.FrequencySlider_2.Enable = 'off';
            app.SawtoothWavePanel = uipanel(app.UIFigure);
            app.SawtoothWavePanel.Title = 'SawtoothWave';
            app.AmplitudeSlider_4Label = uilabel(app.SawtoothWavePanel);
            app.AmplitudeSlider_4Label.Text = 'Amplitude';
            app.AmplitudeSlider_4 = uislider(app.SawtoothWavePanel);
            app.DefaultSineWaveButton = uibutton(app.UIFigure, 'state');
            app.DefaultSineWaveButton.ValueChangedFcn = createCallbackFcn(app, @DefaultSineWaveButtonValueChanged, true);
            app.DefaultSineWaveButton.Text = 'Default SineWave';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.XlimitSliderLabel = uilabel(app.Panel);
            app.XlimitSliderLabel.Text = 'Xlimit';
            app.XlimitSlider = uislider(app.Panel);
            app.YlimitSliderLabel = uilabel(app.Panel);
            app.YlimitSliderLabel.Text = 'Ylimit';
            app.YlimitSlider = uislider(app.Panel);
            app.SampleRateSliderLabel = uilabel(app.Panel);
            app.SampleRateSliderLabel.Text = 'SampleRate';
            app.SampleRateSlider = uislider(app.Panel);
            app.DefaultSquareWaveButton = uibutton(app.UIFigure, 'state');
            app.DefaultSquareWaveButton.ValueChangedFcn = createCallbackFcn(app, @DefaultSquareWaveButtonValueChanged, true);
            app.DefaultSquareWaveButton.Text = 'Default SquareWave';
            app.DefaultTriangularWaveButton = uibutton(app.UIFigure, 'state');
            app.DefaultTriangularWaveButton.ValueChangedFcn = createCallbackFcn(app, @DefaultTriangularWaveButtonValueChanged, true);
            app.DefaultTriangularWaveButton.Text = 'Default TriangularWave';
            app.DefaultSawToothButton = uibutton(app.UIFigure, 'state');
            app.DefaultSawToothButton.Text = 'Default SawTooth';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end